classdef ChangeFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        m_Filelist    matlab.ui.control.ListBox
        m_Folderlist  matlab.ui.control.ListBox
        Image         matlab.ui.control.Image
        m_delete      matlab.ui.control.Button
        m_add         matlab.ui.control.Button
    end

    
    properties (Access = private)
        RootFolder = 'e:\study\Matlab_test' %根文件夹
        CurrentFolder_names %所有子文件夹（仅名字）
        CurrentFile_names %某子文件夹内所有文件（仅名字）
        CurrentFolder %选择的文件夹（仅名字）
        CurrentFile %选择的文件（仅名字）
    end
    
    methods (Access = private)
        
        function m_changeFolderList(app)%%%读取所有文件夹
            folders = dir(app.RootFolder);
            folders(strcmp({folders(:).name}, '.') | strcmp({folders(:).name}, '..')) = [];
            app.CurrentFolder_names = {folders.name};

            %显示子文件夹
            app.m_Folderlist.Items = app.CurrentFolder_names;
        end

        function m_changeFileList(app)%%%读取所有文件名
            files = dir(fullfile(app.CurrentFolder, '*.png'));
            app.CurrentFile_names = {files.name};

            %显示文件
            app.m_Folderlist.Items = app.CurrentFile_names;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.m_changeFolderList;  %显示默认列表内文件
        end

        % Button pushed function: m_add
        function m_addPushed(app, event)
            %获取当前文件夹内所有图片数量（序号）
            files = dir(fullfile(app.CurrentFolder, '*.png'));
            lastcount = length(files);

            %获取当前文件夹名字
            [~, thisFolderName, ~] = fileparts(app.CurrentFolder);

            %用户选择图片
            [filename, filepath] = uigetfile({'*.png'}, '选择要添加的图片');
            if isequal(filename, 0) || isequal(filepath, 0)
                %用户取消选择
                return;
            end

            %确定新名字+新路径
            [~, ~, ext] = fileparts(filename);
            new_filename = [thisFolderName '_' int2str(lastcount+1) ext];
            new_filepath = fullfile(app.CurrentFolder, new_filename);

            %复制
            try
                copyfile(fullfile(filepath, filename), new_filepath);
                msg = '添加成功！';
            catch ME
                msg = ['添加错误： ' ME.message];
            end

            %展示结果
            uilabel(app.UIFigure, 'Text', msg, 'Position', [100, 100, 700, 22]);

            %刷新列表
            app.m_changeFileList;
        end

        % Button pushed function: m_delete
        function m_deletePushed(app, event)
            delete([app.RootFolder '\' app.CurrentFolder '\' app.CurrentFile]);

            %刷新列表
            app.m_changeFileList;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: m_Folderlist
        function m_FolderlistValueChanged(app, event)
            app.CurrentFolder = [app.RootFolder '\' app.m_Folderlist.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.m_add = uibutton(app.UIFigure, 'push');
            app.m_add.ButtonPushedFcn = createCallbackFcn(app, @m_addPushed, true);
            app.m_add.Text = '添加';
            app.m_delete = uibutton(app.UIFigure, 'push');
            app.m_delete.ButtonPushedFcn = createCallbackFcn(app, @m_deletePushed, true);
            app.m_delete.Text = '删除';
            app.Image = uiimage(app.UIFigure);
            app.m_Folderlist = uilistbox(app.UIFigure);
            app.m_Folderlist.Items = {};
            app.m_Folderlist.ValueChangedFcn = createCallbackFcn(app, @m_FolderlistValueChanged, true);
            app.m_Folderlist.Value = {};
            app.m_Filelist = uilistbox(app.UIFigure);
            app.m_Filelist.Items = {};
            app.m_Filelist.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChangeFile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end